classdef T_nD_laplace_diagnostic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        LengthscaleshapeLabel    matlab.ui.control.Label
        lambda_slider            matlab.ui.control.Slider
        PriorvariancedecayLabel  matlab.ui.control.Label
        gamma_slider             matlab.ui.control.Slider
        lap_axes                 matlab.ui.control.UIAxes
        PriorprecisionLabel      matlab.ui.control.Label
        alpha_slider             matlab.ui.control.Slider
    end

    
    properties (Access = private)
        f;
        s;
        s_trans;
        hess_at_mode;
        mode;
        f_at_mode;
        f_sym;
        m0_t;
        rot_det;
        f_interr
        gauss_interr;
        grid_bounds;
        grid_ratio;
        xvals;
        yvals;
        prior_mean_vals;
        grid_size1;
        grid_size2;
        postsurf;
        point_scat;
        true_surf;
        lap_approx;
        rot_scale_mat;
        d;
    end
    
    methods (Access = private)
        
        function c_t = gp_weights(app, x, lambda, gamma, d)
            c_t = (sqrt(pi)*lambda)^d*exp(-(vecnorm(x',2,1).^2 + vecnorm(app.s,2,1).^2)./(4*gamma^2) - vecnorm(x' - app.s,2,1).^2./(4*lambda^2));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, d, r, delt, v)
            app.d = d;
            app.f = @(x) mvtpdf(x, eye(app.d), v);
            s_template = -r:delt:r;
            l = length(s_template);
            app.s = zeros([app.d app.d*(l-1)+1]);
            app.s(1,1:l) = s_template;
            for i = 2:app.d
                app.s(i,(i-2)*(l-1)+l+(1:(l-1))) = s_template([1:((l-1)/2) ((l+3)/2):end]);
            end
            [app.mode, app.f_sym, app.f_at_mode, app.hess_at_mode, ~] = laplace_ingredients(app.f, app.d, false);
            app.hess_at_mode = double(app.hess_at_mode);
            app.mode = double(app.mode);
            app.f_at_mode = double(app.f_at_mode);
            app.m0_t = @(x) app.f_at_mode*exp(sum(((x-app.mode)*app.hess_at_mode).*(x-app.mode), 2)/2);
            [V, D] = eig(app.hess_at_mode);
            app.rot_scale_mat = sqrt(-D)\V';
            app.rot_det = abs(det(app.rot_scale_mat));
            app.s_trans = app.s'*app.rot_scale_mat + app.mode;
        
            app.gauss_interr = app.m0_t(app.s_trans);
            app.f_interr = app.f(app.s_trans);
            
            app.lap_approx = app.rot_det*app.f_at_mode*(2*pi)^(app.d/2);
            
            if app.d == 1
                title(app.UIAxes, 'Full function and GP posterior mean');
                xlabel(app.UIAxes, 'x');
                app.grid_bounds = [min(app.s_trans)-2*app.rot_scale_mat(1,1) max(app.s_trans)+2*app.rot_scale_mat(1,1)];
                app.grid_ratio = 0;
                app.xvals = uniquetol([linspace(app.grid_bounds(1), app.grid_bounds(2), 101) app.s_trans'], 1e-12);
                app.grid_size1 = length(app.xvals);
                app.yvals = 0;
                app.grid_size2 = 0;
                app.prior_mean_vals = app.m0_t(app.xvals');
                app.true_surf = plot(app.UIAxes, app.xvals, app.f(app.xvals'));
                hold(app.UIAxes, 'on')
                app.point_scat = scatter(app.UIAxes, app.s_trans, app.f_interr, 'filled', 'MarkerFaceColor', 'k');
                app.postsurf = gobjects(1);
                
            elseif app.d == 2
                title(app.UIAxes, 'Full function and GP posterior mean');
                xlabel(app.UIAxes, 'x_1');
                ylabel(app.UIAxes, 'x_2');
                app.grid_bounds = [min(app.s_trans(:,1))-2*app.rot_scale_mat(1,1) max(app.s_trans(:,1))+2*app.rot_scale_mat(1,1)...
                    min(app.s_trans(:,2))-2*app.rot_scale_mat(2,2) max(app.s_trans(:,2))+2*app.rot_scale_mat(2,2)];
                app.grid_ratio = (app.grid_bounds(4) - app.grid_bounds(3))/...
                    (app.grid_bounds(2) - app.grid_bounds(1));
                
                [xgrid, ygrid] = meshgrid(uniquetol([linspace(app.grid_bounds(1), app.grid_bounds(2), 101) app.s_trans(:,1)'], 1e-12),...
                    uniquetol([linspace(app.grid_bounds(3), app.grid_bounds(4), 101) app.s_trans(:,2)'], 1e-12));
                
                [app.grid_size1, app.grid_size2] = size(xgrid);
                app.xvals = xgrid(:);
                app.yvals = ygrid(:);
                
                app.prior_mean_vals = reshape(app.m0_t([app.xvals app.yvals]), app.grid_size1, app.grid_size2);
                app.true_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)), ...
                    reshape(app.f([app.xvals app.yvals]), app.grid_size1, app.grid_size2), 'FaceAlpha', 0.4, 'FaceColor', 'r', 'EdgeColor', 'none');
                hold(app.UIAxes, 'on')
                app.point_scat = scatter3(app.UIAxes, app.s_trans(:,1), app.s_trans(:,2), app.f_interr, 'filled', 'MarkerFaceColor', 'k');
                pbaspect(app.UIAxes, [1 app.grid_ratio geomean([app.grid_ratio 1/app.grid_ratio])])
                app.postsurf = gobjects(1);
                
            else
                title(app.UIAxes, {'2-D slice of full function and GP posterior mean', '(x_i = 0 for i > 2)'});
                xlabel(app.UIAxes, 'x_1');
                ylabel(app.UIAxes, 'x_2');
                app.grid_bounds = [min(app.s_trans(:,1))-2*app.rot_scale_mat(1,1) max(app.s_trans(:,1))+2*app.rot_scale_mat(1,1)...
                    min(app.s_trans(:,2))-2*app.rot_scale_mat(2,2) max(app.s_trans(:,2))+2*app.rot_scale_mat(2,2)];
                app.grid_ratio = (app.grid_bounds(4) - app.grid_bounds(3))/...
                    (app.grid_bounds(2) - app.grid_bounds(1));
                
                [xgrid, ygrid] = meshgrid(uniquetol([linspace(app.grid_bounds(1), app.grid_bounds(2), 101) app.s_trans(:,1)'], 1e-12),...
                    uniquetol([linspace(app.grid_bounds(3), app.grid_bounds(4), 101) app.s_trans(:,2)'], 1e-12));
                
                [app.grid_size1, app.grid_size2] = size(xgrid);
                app.xvals = xgrid(:);
                app.yvals = ygrid(:);
                
                app.prior_mean_vals = reshape(app.m0_t([app.xvals app.yvals 0*repelem(app.s_trans(1,1)-0.5, length(app.xvals))' zeros([length(app.xvals) d-3])]), app.grid_size1, app.grid_size2);
                app.true_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)), ...
                    reshape(app.f([app.xvals app.yvals 0*repelem(app.s_trans(1,1)-0.5, length(app.xvals))' zeros([length(app.xvals) d-3])]), app.grid_size1, app.grid_size2), 'FaceAlpha', 0.4, 'FaceColor', 'r', 'EdgeColor', 'none');
                hold(app.UIAxes, 'on')
                app.point_scat = scatter3(app.UIAxes, app.s_trans(1:(2*l-1),1), app.s_trans(1:(2*l-1),2), app.f_interr(1:(2*l-1)), 'filled', 'MarkerFaceColor', 'k');
                app.point_scat = 0;
                pbaspect(app.UIAxes, [1 app.grid_ratio geomean([app.grid_ratio 1/app.grid_ratio])])
                app.postsurf = gobjects(1);
            end
        end

        % Value changed function: alpha_slider, gamma_slider, 
        % lambda_slider
        function SliderValuesChanged(app, event)
            lambda = app.lambda_slider.Value;
            gamma = 2^app.gamma_slider.Value;
            alpha = 10^app.alpha_slider.Value;
            hold(app.lap_axes, 'off')
            
            C0_t = se_decay_gram(app.s, lambda, gamma);
            pointz = C0_t\(app.f_interr - app.gauss_interr);

            if app.d == 1
                delete(app.postsurf);

                c_t_cells = arrayfun(@(i) gp_weights(app, app.xvals(i)./app.rot_scale_mat(1,1), lambda, gamma, app.d),...
                    1:(app.grid_size1), 'UniformOutput', false);

                c_t_vals = cat(1, c_t_cells{:});

                warning('off');
                app.postsurf = plot(app.UIAxes, app.xvals, app.prior_mean_vals + c_t_vals*pointz, 'col', 'r');
                legend(app.UIAxes, [app.postsurf app.true_surf app.point_scat], {'GP posterior mean', 'True target function', 'Interrogation points'},...
                    'Location', 'northoutside');
            elseif app.d == 2
                delete(app.postsurf);
                
                c_t_cells = arrayfun(@(i) gp_weights(app, [app.xvals(i)./app.rot_scale_mat(1,1) app.yvals(i)./app.rot_scale_mat(2,2)], lambda, gamma, app.d),...
                    1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);
    
                c_t_vals = cat(1, c_t_cells{:});

                warning('off');
                app.postsurf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
                    app.prior_mean_vals + reshape(c_t_vals*pointz, app.grid_size1, app.grid_size2),...
                    'FaceAlpha', 0.65);
                legend(app.UIAxes, [app.postsurf app.true_surf app.point_scat], {'GP posterior mean', 'True target function', 'Interrogation points'},...
                    'Location', 'northoutside');
            else
                delete(app.postsurf);
                
                c_t_cells = arrayfun(@(i) gp_weights(app, [app.xvals(i)./app.rot_scale_mat(1,1) app.yvals(i)./app.rot_scale_mat(2,2) 0*(app.s_trans(1,1)-0.5)/app.rot_scale_mat(3,3) zeros([1 app.d-3])], lambda, gamma, app.d),...
                    1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);
    
                c_t_vals = cat(1, c_t_cells{:});

                warning('off');
                app.postsurf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
                    app.prior_mean_vals + reshape(c_t_vals*pointz, app.grid_size1, app.grid_size2),...
                    'FaceAlpha', 0.65);
                legend(app.UIAxes, [app.postsurf app.true_surf], {'GP posterior mean', 'True target function'},...
                    'Location', 'northoutside');
                
            end
            
            C0_cross = se_decay_cross_covar(app.s, lambda, gamma, -Inf, Inf);
            
            C0 = ((4*gamma^2*lambda^2*pi^(3/2))/(2*gamma^2 + lambda^2)^(1/2))^app.d;
            post_var = app.rot_det^2*(C0 - (C0_cross/C0_t)*C0_cross')/alpha^app.d;
            
            post_mean = app.lap_approx + app.rot_det*C0_cross*pointz;
            
            lower_bound = double(min([norminv(0.025, post_mean, sqrt(post_var))...
                1 app.lap_approx]));
            upper_bound = double(max([norminv(0.975, post_mean, sqrt(post_var))...
                1 app.lap_approx]));
            lap_dist = fplot(app.lap_axes, @(x) normpdf(x, post_mean, sqrt(post_var)),...
                [lower_bound - 0.25*abs(lower_bound) upper_bound + 0.25*abs(upper_bound)], '-k');
            hold(app.lap_axes, 'on')
            lap_line = xline(app.lap_axes,app.lap_approx, '--b', 'LineWidth', 1);
            true_line = xline(app.lap_axes,1, '--r','LineWidth', 1);
            quant_line = xline(app.lap_axes,norminv(0.025, post_mean, sqrt(post_var)), ':k',...
                'LineWidth', 1.25);
            quant_line = xline(app.lap_axes,norminv(0.975, post_mean, sqrt(post_var)), ':k',...
                'LineWidth', 1.25);
            legend(app.lap_axes,[lap_dist, quant_line, lap_line, true_line],...
                {'Posterior of integral', '95% region',...
                'Laplace approximation value', 'True integral value'}, 'Location', 'southoutside');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.LengthscaleshapeLabel = uilabel(app.UIFigure);
            app.LengthscaleshapeLabel.Text = 'Length-scale (shape): λ';
            app.lambda_slider = uislider(app.UIFigure);
            app.lambda_slider.Limits = [0.01 1.5];
            app.lambda_slider.MajorTicks = [0.01 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5];
            app.lambda_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.lambda_slider.MinorTicks = [];
            app.lambda_slider.Value = 0.5;
            app.PriorvariancedecayLabel = uilabel(app.UIFigure);
            app.PriorvariancedecayLabel.Text = 'Prior variance decay: γ';
            app.gamma_slider = uislider(app.UIFigure);
            app.gamma_slider.Limits = [-1 7.64385618977472];
            app.gamma_slider.MajorTicks = [-1 0 1 2.32192809488736 3.32192809488736 4.64385618977472 5.64385618977472 6.64385618977472 7.64385618977472];
            app.gamma_slider.MajorTickLabels = {'0.5', '1', '2', '5', '10', '25', '50', '100', '200'};
            app.gamma_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.gamma_slider.MinorTicks = [];
            app.gamma_slider.Value = 3.32192809488736;
            app.lap_axes = uiaxes(app.UIFigure);
            title(app.lap_axes, 'Laplace Approximation Diagnostic')
            xlabel(app.lap_axes, '')
            ylabel(app.lap_axes, '')
            app.PriorprecisionLabel = uilabel(app.UIFigure);
            app.PriorprecisionLabel.Text = 'Prior precision: α';
            app.alpha_slider = uislider(app.UIFigure);
            app.alpha_slider.Limits = [1 5];
            app.alpha_slider.MajorTicks = [1 1.69897000433602 2 2.69897000433602 3 4 5];
            app.alpha_slider.MajorTickLabels = {'10', '50', '100', '500', '1000', '10000', '100000'};
            app.alpha_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.alpha_slider.MinorTicks = [];
            app.alpha_slider.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T_nD_laplace_diagnostic_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end