classdef banana_laplace_diagnostic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        LengthscaleLabel            matlab.ui.control.Label
        lambda_slider               matlab.ui.control.Slider
        PriorvariancedecayLabel     matlab.ui.control.Label
        gamma_slider                matlab.ui.control.Slider
        lap_axes                    matlab.ui.control.UIAxes
        PriorprecisionLabel         matlab.ui.control.Label
        alpha_slider                matlab.ui.control.Slider
        GeneratefiguretosaveButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        s; % Interrogation points on "normalized" scale
        rot_scale_mat; % Matrix used to rotate/scale/shift domain.
        rot_det; % Determinant of rot_scale_mat. Needed to put diagnostic back on "original" scale
        f_at_mode; % True function value at the mode
        f_interr % True function values at the interrogation points
        gauss_interr; % Prior mean (Gaussian approx.) values at interrogation points
        xvals; % Points on x_1 axis for plotting
        yvals; % Points on x_2 axis for plotting
        prior_mean_vals; % Values of prior Gaussian mean on a fine grid
        grid_size1; grid_size2; % Size of mesh grid used for plotting
        postsurf; % Surface plot for posterior mean of GP
        point_scat; % Scatterplot of interrogation points and the corresponding function values
        true_surf; % Surface plot of true function
        lap_approx; % Laplace approximation
    end
    
    methods (Access = private)
        
        function c_t = gp_weights(app, x, lambda, gamma)
            % Calculates the vector C0_t(x, s). Needed for the posterior
            % mean
            c_t = pi*lambda^2*exp(-(vecnorm(x).^2 + vecnorm(app.s).^2)./(4*gamma^2) - vecnorm(x' - app.s).^2./(4*lambda^2));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, nu, r, delt)
            % nu = Number of "spokes" you want in interrogation grid. e.g.
            % 4 gives the usual cross; nu = 8 adds spokes along the
            % 45-degree angles, etc.
            % r = Number of standard deviations for which the grid should
            % extend.
            % delt = Increments (in standard deviations) at which to put
            % interrogation points. e.g. nu = 4, r = 3, delt = 1 puts
            % points at +-0,1,2,3 s.d.'s along each axis
            
            % True function
            g = @(x)(sqrt(3.0).*exp(x(:,1).^2.*(-1.0./6.0)-(x(:,2)-x(:,1).^2./2.0+3.0./2.0).*(x(:,2)./2.0-x(:,1).^2./4.0+3.0./4.0)))./(pi.*6.0);
            f = @(x) g([(x(:,1) + x(:,2))/sqrt(2) (x(:,1) - x(:,2))/sqrt(2)]);
            % Interrogation grid
            [x1, y1] = pol2cart(2*pi/nu*(0:(nu-1)), 1);
            app.s = [kron(delt:delt:r, [x1; y1]) [0; 0]];
            
            % Stuff for Gaussian approximation
            [mode, ~, f_at_mode, hess_at_mode, ~] = laplace_ingredients(f, 2, false);
            hess_at_mode = double(hess_at_mode);
            mode = double(mode);
            app.f_at_mode = double(f_at_mode);
            m0_t = @(x) app.f_at_mode*exp(sum(((x-mode)*hess_at_mode).*(x-mode), 2)/2);
            
            % Stuff to scale ("normalize") domain
            % NOTE: here we make rot_scale_mat manually, as the usual
            % eigendecomposition for hess_at_mode produces an antidiagonal
            % matrix (i.e. switches the axes). This manual diagonal one is
            % still valid.
            app.rot_scale_mat = [sqrt(3) 0; 0 1];
            app.rot_det = abs(det(app.rot_scale_mat));
            s_trans = app.s'*app.rot_scale_mat + mode;
            
            app.gauss_interr = m0_t(s_trans);
            app.f_interr = f(s_trans);
            app.lap_approx = app.rot_det*app.f_at_mode*2*pi;
            
            % Stuff for plotting
            grid_bounds = [min(s_trans(:,1))-2*app.rot_scale_mat(1,1) max(s_trans(:,1))+2*app.rot_scale_mat(1,1)...
                min(s_trans(:,2))-2*app.rot_scale_mat(2,2) max(s_trans(:,2))+2*app.rot_scale_mat(2,2)];
            grid_ratio = (grid_bounds(4) - grid_bounds(3))/...
                (grid_bounds(2) - grid_bounds(1));
            [xgrid, ygrid] = meshgrid(uniquetol([linspace(grid_bounds(1), grid_bounds(2), 101) s_trans(:,1)'], 1e-12),...
                uniquetol([linspace(grid_bounds(3), grid_bounds(4), 101) s_trans(:,2)'], 1e-12));
            [app.grid_size1, app.grid_size2] = size(xgrid);
            app.xvals = xgrid(:);
            app.yvals = ygrid(:);
            app.prior_mean_vals = reshape(m0_t([app.xvals app.yvals]), app.grid_size1, app.grid_size2);
            app.true_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)), ...
                reshape(f([app.xvals app.yvals]), app.grid_size1, app.grid_size2), 'FaceAlpha', 0.5);
            colormap winter
            hold(app.UIAxes, 'on')
            app.point_scat = scatter3(app.UIAxes, s_trans(:,1), s_trans(:,2), app.f_interr, 'filled', 'MarkerFaceColor', 'k');
            pbaspect(app.UIAxes, [1 grid_ratio geomean([grid_ratio 1/grid_ratio])])
            app.postsurf = gobjects(1);
        end

        % Value changed function: alpha_slider, gamma_slider, 
        % lambda_slider
        function SliderValuesChanged(app, event)
            lambda = app.lambda_slider.Value;
            disp(['lambda: ' num2str(lambda)]);
            gamma = 2^app.gamma_slider.Value;
            disp(['gamma: ' num2str(gamma)]);
            alpha = 10^app.alpha_slider.Value;
            disp(['alpha: ' num2str(alpha)]);

            % Initialize
            delete(app.postsurf);
            hold(app.lap_axes, 'off')
            
            % Get necessary stuff to calculate posterior GP mean given
            % hyperparameters
            C0_t = se_decay_gram(app.s, lambda, gamma);
            pointz = C0_t\(app.f_interr - app.gauss_interr);
            % Note the shifting of the second argument because the mode is
            % NOT at the origin
            c_t_cells = arrayfun(@(i) gp_weights(app, [app.xvals(i)/sqrt(3) app.yvals(i) + 1.5], lambda, gamma),...
                1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);
            c_t_vals = cat(1, c_t_cells{:});

            % Plot posterior GP mean
            warning('off');
            app.postsurf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
                app.prior_mean_vals + reshape(c_t_vals*pointz, app.grid_size1, app.grid_size2),...
                'FaceAlpha', 0.4, 'FaceColor', 'r', 'EdgeColor', 'none');
            legend(app.UIAxes, [app.postsurf app.true_surf app.point_scat], {'GP posterior mean', 'True target function', 'Interrogation points'},...
                'Location', 'northoutside');
            
            % Get posterior mean and variance for integral diagnostic
            C0_cross = se_decay_cross_covar(app.s, lambda, gamma, -Inf, Inf);
            C0 = (16*gamma^4*lambda^4*pi^3)/(2*gamma^2 + lambda^2);
            post_var = app.f_at_mode^2*app.rot_det^2*(C0 - (C0_cross/C0_t)*C0_cross')/alpha^2;
            post_mean = app.lap_approx + app.rot_det*C0_cross*pointz;
            
            % Plot integral diagnostic
            lower_bound = double(min([norminv(0.025, post_mean, sqrt(post_var))...
                1 app.lap_approx]));
            upper_bound = double(max([norminv(0.975, post_mean, sqrt(post_var))...
                1 app.lap_approx]));
            lap_dist = fplot(app.lap_axes, @(x) normpdf(x, post_mean, sqrt(post_var)),...
                [lower_bound - 0.25*(upper_bound - lower_bound) upper_bound + 0.25*(upper_bound - lower_bound)], '-k');
            hold(app.lap_axes, 'on')
            lap_line = xline(app.lap_axes,app.lap_approx, '--b', 'LineWidth', 1);
            true_line = xline(app.lap_axes,1, '--r','LineWidth', 1);
            quant_line = xline(app.lap_axes,norminv(0.025, post_mean, sqrt(post_var)), ':k',...
                'LineWidth', 1.25);
            quant_line = xline(app.lap_axes,norminv(0.975, post_mean, sqrt(post_var)), ':k',...
                'LineWidth', 1.25);
            legend(app.lap_axes,[lap_dist, quant_line, lap_line, true_line],...
                {'Posterior of integral', '95% region',...
                'Laplace approximation value', 'True integral value'}, 'Location', 'southoutside');
        end

        % Button pushed function: GeneratefiguretosaveButton
        function GeneratefiguretosaveButtonPushed(app, event)
            fig = tiledlayout(1,3, 'TileSpacing', 'Compact', 'Padding', 'Compact');
            title(fig, strcat(['\lambda = ' num2str(app.lambda_slider.Value) ', \gamma = ' num2str(2^app.gamma_slider.Value)...
                ', \alpha = ' num2str(10^app.alpha_slider.Value)]));
            %hSub1 = subplot('Position', [0 0 1 1]);
            hSub1 = nexttile([1 2]);
            %disp(app.UIAxes.Children);
            copyobj(app.UIAxes.Children, hSub1);
            xlabel('x_1');
            ylabel('x_2');
            pbaspect(hSub1, app.UIAxes.PlotBoxAspectRatio)
            legend({'\it m_1^t', 'Interrogation points', '\beta'}, 'Location', 'southoutside');
            title('Banana function and GP posterior mean');
            
            hSub2 = nexttile;
            copyobj(app.lap_axes.Children, hSub2);
            legend({'Posterior distribution of integral', 'L(\beta)', 'F', '95% region'}, 'Location', ...
                'southoutside');
            title('Laplace Approximation Diagnostic')
            
            %copyobj(app.UIAxes.Legend, hSub1);
            %sub2 = copyobj(app.lap_axes.Parent, fig);
            %saveas(fig, 'fig.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Full function and GP posterior mean')
            xlabel(app.UIAxes, 'X_1')
            ylabel(app.UIAxes, 'X_2')
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LengthscaleLabel = uilabel(app.UIFigure);
            app.LengthscaleLabel.Text = 'Length-scale: λ';
            app.lambda_slider = uislider(app.UIFigure);
            app.lambda_slider.Limits = [0.01 7];
            app.lambda_slider.MajorTicks = [0.01 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4];
            app.lambda_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.lambda_slider.MinorTicks = [];
            app.lambda_slider.Value = 5.3909;
            app.PriorvariancedecayLabel = uilabel(app.UIFigure);
            app.PriorvariancedecayLabel.Text = 'Prior variance decay: γ';
            app.gamma_slider = uislider(app.UIFigure);
            app.gamma_slider.Limits = [-1 7.64385618977472];
            app.gamma_slider.MajorTicks = [-1 0 1 2.32192809488736 3.32192809488736 4.64385618977472 5.64385618977472 6.64385618977472 7.64385618977472];
            app.gamma_slider.MajorTickLabels = {'0.5', '1', '2', '5', '10', '25', '50', '100', '200'};
            app.gamma_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.gamma_slider.MinorTicks = [];
            app.gamma_slider.Value = -0.19660762117295;
            app.lap_axes = uiaxes(app.UIFigure);
            title(app.lap_axes, 'Laplace Approximation Diagnostic')
            xlabel(app.lap_axes, 'X')
            ylabel(app.lap_axes, 'Y')
            app.PriorprecisionLabel = uilabel(app.UIFigure);
            app.PriorprecisionLabel.Text = 'Prior precision: α';
            app.alpha_slider = uislider(app.UIFigure);
            app.alpha_slider.Limits = [-2 4];
            app.alpha_slider.MajorTicks = [0 0.698970004336019 1 1.69897000433602 2 3 4];
            app.alpha_slider.MajorTickLabels = {'1', '5', '10', '50', '100', '1000', '10000'};
            app.alpha_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.alpha_slider.MinorTicks = [];
            app.alpha_slider.Value = -0.677325968248576;
            app.GeneratefiguretosaveButton = uibutton(app.UIFigure, 'push');
            app.GeneratefiguretosaveButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefiguretosaveButtonPushed, true);
            app.GeneratefiguretosaveButton.Text = 'Generate figure to save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = banana_laplace_diagnostic_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end