classdef T_2D_laplace_diagnostic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        LengthscaleLabel      matlab.ui.control.Label
        lambda_slider         matlab.ui.control.Slider
        MeasurescaleLabel     matlab.ui.control.Label
        gamma_slider          matlab.ui.control.Slider
        lap_axes              matlab.ui.control.UIAxes
        GeneratefigureButton  matlab.ui.control.Button
        UseDefaultsButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        s_star; % Preliminary interrogation grid
        s; % Actual interrogation grid
        sqrt_det_inv_hess; % sqrt(det(-H^-1))
        tau_at_mode; % Value of the test function at the mode (origin)
        tau_interr % tau(s)
        gauss_interr; % Values of the Gaussian approximation to tau at the points s
        xvals; % Points on x_1 axis for plotting
        yvals; % Points on x_2 axis for plotting
        grid_size1; grid_size2; % Size of grid used for plotting
        tau_vals; % Values of tau at [xvals yvals]
        gauss_approx_vals; % Values of Gaussian approximation to tau at [xvals yvals]
        diff_surf; % Surface plot of mx_1*g - tau (the difference between the "un-normalized" posterior GP mean and tau)
        point_scat; % Scatterplot of interrogation points
        v; % Degrees of freedom for tau
        lap_approx; % Laplace approximation to \int tau
        mah_dist_s; % Mahalanobis distances between vectors in s
        mah_dist_x_s; % Mahalan
        g;
        alpha;

    end
    
    methods (Access = private)
        function c_t = Cx_0(app, r, lambda)
            % Calculates the vector C0_t(x, s). Needed for the posterior
            % mean
            c_t = exp(-r.^2/(2*lambda^2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, r, delt, v)
            % nu = Number of "spokes" you want in interrogation grid. e.g.
            % 4 gives the usual cross; nu = 8 adds spokes along the
            % 45-degree angles, etc.
            % r = Number of standard deviations for which the grid should
            % extend.
            % delt = Increments (in standard deviations) at which to put
            % interrogation points. e.g. nu = 4, r = 3, delt = 1 puts
            % points at +-0,1,2,3 s.d.'s along each axis
            % v = Degrees of freedom for T-distribution (true function)
            %set(0,'defaulttextinterpreter','latex')
            %set(app.UIAxes,'FontName', 'cmr12');
            %set(0, 'Defa '
            
            set(app.UIAxes, 'FontSize', 15);
            set(app.lap_axes, 'FontSize', 15);
            
            
            app.v = v;
            d = 2;
            tau = @(x) mvtpdf(x, eye(2), v); % True function
            
            % Interrogation grid
            %[x1, y1] = pol2cart(2*pi/nu*(0:(nu-1)), 1);
            s_template = -r:delt:r;
            l = length(s_template);
            app.s_star = zeros([d*(l-1)+1 d]);
            app.s_star(1:l, 1) = s_template;
            for i = 2:d
                app.s_star((i-2)*(l-1)+l+(1:(l-1)), i) = s_template([1:((l-1)/2) ((l+3)/2):end]);
            end
            
            % Stuff for Gaussian approximation
            
            app.tau_at_mode = tau(zeros([1 d]));

            hess_at_mode = -diag(repelem((v+d)/v, d));
            
            app.sqrt_det_inv_hess = -1/hess_at_mode(1,1);
            
            %disp(app.sqrt_det_inv_hess)
            
            gauss_approx = @(x) app.tau_at_mode*exp(sum((x*hess_at_mode).*x,2)/2);
            
            app.g = @(x, gamma) mvnpdf(x, zeros([1 d]), -gamma^2*inv(hess_at_mode));

            app.s = sqrt(app.sqrt_det_inv_hess)*app.s_star;
            
            
            app.gauss_interr = gauss_approx(app.s);
            app.tau_interr = tau(app.s);
            app.lap_approx = app.sqrt_det_inv_hess*app.tau_at_mode*2*pi;
            
            % Stuff for plotting
            grid_bounds = [min(app.s(:,1))-2*sqrt(app.sqrt_det_inv_hess) max(app.s(:,1))+2*sqrt(app.sqrt_det_inv_hess)...
                min(app.s(:,2))-2*sqrt(app.sqrt_det_inv_hess) max(app.s(:,2))+2*sqrt(app.sqrt_det_inv_hess)];
            grid_ratio = (grid_bounds(4) - grid_bounds(3))/...
                (grid_bounds(2) - grid_bounds(1));
            [xgrid, ygrid] = meshgrid(linspace(grid_bounds(1), grid_bounds(2), 81),...
                linspace(grid_bounds(3), grid_bounds(4), 81));
            [app.grid_size1, app.grid_size2] = size(xgrid);
            app.xvals = xgrid(:);
            app.yvals = ygrid(:);
            
            app.mah_dist_s = pdist2(app.s_star, app.s_star);

            app.mah_dist_x_s = pdist2([app.xvals app.yvals]/sqrt(app.sqrt_det_inv_hess), app.s_star);
            
            app.gauss_approx_vals = reshape(gauss_approx([app.xvals app.yvals]), app.grid_size1, app.grid_size2);
            app.tau_vals = reshape(tau([app.xvals app.yvals]), app.grid_size1, app.grid_size2);
            title(app.UIAxes, ['$$m^x_1 \cdot g - \tau_{' num2str(app.v) ', ' num2str(d) '}$$'], 'Interpreter',"latex", 'FontSize', 24);
            xlabel(app.UIAxes, 'x_1', 'FontSize', 15);
            ylabel(app.UIAxes, 'x_2', 'FontSize', 15);
            app.point_scat = scatter3(app.UIAxes, app.s(:,1), app.s(:,2), zeros([1 size(app.s,1)]), 64, 'filled', 'MarkerFaceColor', 'k');
            
            hold(app.UIAxes, 'on')
            pbaspect(app.UIAxes, [1 grid_ratio geomean([grid_ratio 1/grid_ratio])])
            app.diff_surf = gobjects(1);
            
            title(app.lap_axes, 'Posterior of $F$, $\mathcal{N}\left(m_1, C_1\right)$', 'Interpreter', 'latex', 'FontSize', 20);
        end

        % Value changed function: gamma_slider, lambda_slider
        function SliderValuesChanged(app, event)
            lambda = app.lambda_slider.Value;
            disp(['lambda: ' num2str(lambda)]);
            gamma = 2^app.gamma_slider.Value;
            disp(['gamma: ' num2str(gamma)]);
          
            % Initialize
            delete(app.diff_surf);
            hold(app.lap_axes, 'off')
            
            % Get necessary stuff to calculate posterior GP mean given
            % hyperparameters
            gram_mat = Cx_0(app, app.mah_dist_s, lambda);
            pointz = gram_mat\((app.tau_interr - app.gauss_interr)./app.g(app.s, gamma));
            %disp((app.tau_interr - app.gauss_interr)./app.g(app.s, gamma))
            
            GP_weightz = Cx_0(app, app.mah_dist_x_s, lambda);
            % Plot posterior GP mean
            warning('off');
            app.diff_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
                app.gauss_approx_vals + reshape(app.g([app.xvals app.yvals], gamma).*(GP_weightz*pointz), app.grid_size1, app.grid_size2) - app.tau_vals,...
                'FaceAlpha', 0.6, 'EdgeColor', [0.5 0.5 0.5]);
            legend(app.UIAxes, [app.point_scat], {'Interrogation points'},...
                'Location', 'northoutside');
            
            % Get posterior mean and variance for integral diagnostic
            int_weightz = (lambda^2/(gamma^2+lambda^2))*exp(-sum(app.s_star.^2, 2)/(2*(gamma^2+lambda^2)));    

            C0 = lambda^2/(2*gamma^2 + lambda^2);
            post_var_unscaled = C0 - (int_weightz'/gram_mat)*int_weightz;
            
            
            post_mean = app.lap_approx + int_weightz'*pointz;
            

            app.alpha = abs(1.96*app.tau_at_mode*sqrt(post_var_unscaled)*app.sqrt_det_inv_hess/(post_mean - app.lap_approx));
            disp(['alpha: ' num2str(app.alpha)]);
            disp(app.sqrt_det_inv_hess^2)
            post_var = post_var_unscaled*app.sqrt_det_inv_hess^2*app.tau_at_mode^2/app.alpha^2;
            
            disp(['Posterior integral mean: ' num2str(post_mean)]);
            disp(['Posterior integral variance: ' num2str(post_var)]);
            
            % Plot integral diagnostic
            lower_bound = norminv(0.025, post_mean, sqrt(post_var));
            upper_bound = norminv(0.975, post_mean, sqrt(post_var));
            lap_dist = fplot(app.lap_axes, @(x) normpdf(x, post_mean, sqrt(post_var)),...
                [lower_bound - 0.35*(upper_bound - lower_bound) upper_bound + 0.35*(upper_bound - lower_bound)], '-k','LineWidth', 1.5);
            hold(app.lap_axes, 'on')
            lap_line = xline(app.lap_axes,app.lap_approx, '--b', 'LineWidth', 1.25);
            true_line = xline(app.lap_axes,1, '--r','LineWidth', 1.25);
            quant_line = xline(app.lap_axes, lower_bound, ':k',...
                'LineWidth', 1.35);
            quant_line = xline(app.lap_axes, upper_bound, ':k',...
                'LineWidth', 1.35);
            legend(app.lap_axes,[lap_dist, quant_line, lap_line, true_line],...
                {'Integral posterior', '95% interval',...
                'Laplace approximation', 'True integral value'}, 'Location', 'southoutside');
        end

        % Button pushed function: GeneratefigureButton
        function GeneratefigureButtonPushed(app, event)
            
            fig = tiledlayout(1,3, 'TileSpacing', 'Compact', 'Padding', 'Compact');
            
            %
            %title(fig, ['$\lambda = ' num2str(app.lambda_slider.Value) '$, $\gamma = ' num2str(2^app.gamma_slider.Value),...
            %    '$, $\alpha = ' num2str(app.alpha) '$'], 'Interpreter', 'latex', 'FontSize', 20);
            %hSub1 = subplot('Position', [0 0 1 1]);
            hSub1 = nexttile([1 2]);
            set(gca, 'FontSize', 16);
            
            %disp(app.UIAxes.Children);
            copyobj(app.UIAxes.Children, hSub1);
            set(gca,'TickLabelInterpreter','latex'); 
 
            title(['$$m^x_1 \cdot g - \tau_{' num2str(app.v) ', ' num2str(2) '}$$'], 'Interpreter',"latex", 'FontSize', 20);
            xlabel('$x_1$', 'Interpreter', 'latex', 'FontSize', 16);
            ylabel('$x_2$', 'Interpreter', 'latex', 'FontSize', 16);
            legend({'Interrogation points'},...
                'Location', 'southoutside', 'interpreter', 'latex', 'FontSize', 14);
            pbaspect(hSub1, app.UIAxes.PlotBoxAspectRatio)
            
            hSub2 = nexttile;
            set(gca, 'FontSize', 16)
            copyobj(app.lap_axes.Children, hSub2);
            set(gca,'TickLabelInterpreter','latex'); 
            legend({'Integral posterior', '95\% interval',...
                'Laplace approximation', 'True integral value'}, 'Location', 'southoutside', 'interpreter', 'latex', 'FontSize', 14);
            title(gca, 'Posterior of $F$, $\mathcal{N}\left(m_1, C_1\right)$', 'Interpreter', 'latex', 'FontSize', 20);
            
            %copyobj(app.UIAxes.Legend, hSub1);
            %sub2 = copyobj(app.lap_axes.Parent, fig);
            %saveas(fig, 'fig.png');

        end

        % Button pushed function: UseDefaultsButton
        function UseDefaultsButtonPushed(app, event)
            app.lambda_slider.Value = 4.2240;
            app.gamma_slider.Value = log2(sqrt(1.5*40/37));
%             app.lambda_slider.Value = 0.0729;
%             app.gamma_slider.Value = log2(3);
            SliderValuesChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Full function and GP posterior mean')
            xlabel(app.UIAxes, 'X_1')
            ylabel(app.UIAxes, 'X_2')
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LengthscaleLabel = uilabel(app.UIFigure);
            app.LengthscaleLabel.Text = 'Length-scale: λ';
            app.lambda_slider = uislider(app.UIFigure);
            app.lambda_slider.Limits = [0.01 20];
            app.lambda_slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.lambda_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.lambda_slider.MinorTicks = [];
            app.lambda_slider.Value = 2.1409;
            app.MeasurescaleLabel = uilabel(app.UIFigure);
            app.MeasurescaleLabel.Text = 'Measure scale: γ';
            app.gamma_slider = uislider(app.UIFigure);
            app.gamma_slider.Limits = [-1 7.64385618977472];
            app.gamma_slider.MajorTicks = [-1 0 1 2.32192809488736 3.32192809488736 4.64385618977472 5.64385618977472 6.64385618977472 7.64385618977472];
            app.gamma_slider.MajorTickLabels = {'0.5', '1', '2', '5', '10', '25', '50', '100', '200'};
            app.gamma_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.gamma_slider.MinorTicks = [];
            app.gamma_slider.Value = 0.556237364629206;
            app.lap_axes = uiaxes(app.UIFigure);
            title(app.lap_axes, '')
            xlabel(app.lap_axes, '')
            ylabel(app.lap_axes, '')
            app.GeneratefigureButton = uibutton(app.UIFigure, 'push');
            app.GeneratefigureButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefigureButtonPushed, true);
            app.GeneratefigureButton.Text = 'Generate figure';
            app.UseDefaultsButton = uibutton(app.UIFigure, 'push');
            app.UseDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @UseDefaultsButtonPushed, true);
            app.UseDefaultsButton.Text = 'Use Defaults';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T_2D_laplace_diagnostic_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end