classdef T_nD_diff_laplace_diagnostic_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GeneratefiguretosaveButton  matlab.ui.control.Button
        alpha_slider                matlab.ui.control.Slider
        PriorprecisionLabel         matlab.ui.control.Label
        gamma_slider                matlab.ui.control.Slider
        PriorvariancedecayLabel     matlab.ui.control.Label
        lambda_slider               matlab.ui.control.Slider
        LengthscaleLabel            matlab.ui.control.Label
        lap_axes                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s_star; % Preliminary interrogation grid
        s; % Actual interrogation grid
        sqrt_det_inv_hess; % sqrt(det(-H^-1))
        logf_at_mode; % Value of the test function at the mode (origin)
        f_interr % tau(s)
        gauss_interr; % Values of the Gaussian approximation to tau at the points s
        xvals; % Points on x_1 axis for plotting
        yvals; % Points on x_2 axis for plotting
        grid_size1; grid_size2; % Size of grid used for plotting
        true_vals; % Values of tau at [xvals yvals]
        prior_mean_vals; % Values of Gaussian approximation to tau at [xvals yvals]
        diff_surf; % Surface plot of mx_1*g - tau (the difference between the "un-normalized" posterior GP mean and tau)
        point_scat; % Scatterplot of interrogation points
        v; % Degrees of freedom for tau
        lap_approx; % Laplace approximation to \int tau
        mah_dist_s; % Mahalanobis distances between vectors in s
        mah_dist_x_s; % Mahalan
        g;
        alpha;
        d;
        rot_scale_mat; % Matrix used to rotate/scale/shift domain.
        rot_det; % Determinant of rot_scale_mat. Needed to put diagnostic back on "original" scale
        T_elem;
    end
    
    methods (Access = private)
        
        function c_t = gp_weights(app, x, lambda, gamma, d)
            % Calculates the vector C0_t(x, s). Needed for the posterior
            % mean
            c_t = exp(-(vecnorm(x',2,1).^2 + vecnorm(app.s,2,1).^2)./(4*gamma^2) - vecnorm(x' - app.s,2,1).^2./(4*lambda^2));
        end
        
        function c_t = Cx_0(app, r, lambda)
            % Calculates the vector C0_t(x, s). Needed for the posterior
            % mean
            c_t = exp(-r.^2/(2*lambda^2));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, d, r, delt, v, ind)
            % d = Dimensionality of T-distribution
            % r = Number of standard deviations for which the grid should
            % extend.
            % delt = Increments (in standard deviations) at which to put
            % interrogation points. e.g. r = 3, delt = 1 puts
            % points at +-0,1,2,3 s.d.'s along each axis
            % v = Degrees of freedom for T-distribution (true function)
            % pval: level of significane to use for rejection. Consider
            % varying this with d
            % ind: logical; controls whether the true function is a
            % product of (independent) 1-D T-distributions (true) or a
            % multivariate (dependent, but uncorrelated) T-distribution
            % (false)
            app.v = v;
            app.d = d;
            %app.pval = pval;
            set(app.UIAxes, 'FontSize', 16);
            set(app.lap_axes, 'FontSize', 16);
            set(app.UIAxes,'TickLabelInterpreter','latex'); 
            set(app.lap_axes,'TickLabelInterpreter','latex'); 
            
            % True function
            if ind
                f = @(x) prod(tpdf(x, app.v), 2);
                hess_at_mode = diag(repelem(-(app.v+1)/app.v, app.d));
                app.logf_at_mode = app.d*(gammaln((app.v+1)/2) - gammaln(app.v/2) - log(app.v*pi)/2);
                app.T_elem = sqrt(app.v/(app.v+1));
            else
                f = @(x) mvtpdf(x, eye(app.d), app.v);
                hess_at_mode = diag(repelem(-(app.v+app.d)/app.v, app.d));
                app.logf_at_mode = gammaln((app.v+app.d)/2) - gammaln(app.v/2) - app.d*log(app.v*pi)/2;
                app.T_elem = sqrt(app.v/(app.v+app.d));
            end
            
            % Interrogation grid
            s_template = -r:delt:r;
            l = length(s_template);
            app.s_star = zeros([d*(l-1)+1 d]);
            app.s_star(1:l, 1) = s_template;
            for i = 2:d
                app.s_star((i-2)*(l-1)+l+(1:(l-1)), i) = s_template([1:((l-1)/2) ((l+3)/2):end]);
            end
%             s_template = [-r:delt:-delt delt:delt:r];
%             l = length(s_template);
%             app.s = zeros([app.d app.d*l]);
%             app.s(1,1:l) = s_template;
%             for i = 1:app.d
%                 app.s(i,(i-1)*l+(1:l)) = s_template;
%             end
            %disp('Preliminary interrogation points made');
            
            % Stuff for Gaussian approximation
            %[mode, ~, f_at_mode, hess_at_mode, ~] = laplace_ingredients(f, d, false);
            m0_t = @(x) exp(app.logf_at_mode + sum((x*hess_at_mode).*x, 2)/2);
            app.g = @(x, gamma) mvnpdf(x, zeros([1 d]), -gamma^2*inv(hess_at_mode));
            % Stuff to scale ("normalize") domain
            
            [V, D] = eig(hess_at_mode);
            app.rot_scale_mat = sqrt(-D)\V';
            app.rot_det = abs(det(app.rot_scale_mat));
            app.s = app.T_elem*app.s_star;
            %disp(s_trans(1:7,1:2))
            app.gauss_interr = m0_t(app.s);
            app.f_interr = log(f(app.s));
            %disp(log(app.f_interr) - log(app.f_at_mode))
            %disp(mean((app.f_interr -app.gauss_interr)/app.f_at_mode))
            app.lap_approx = exp(app.logf_at_mode + app.d*(log(app.T_elem) + log(2*pi)/2));
            disp(app.lap_approx)
            app.mah_dist_s = pdist2(app.s_star, app.s_star);


            % Stuff for plotting
            if app.d == 1
                title(app.UIAxes, ['$$m^x_1 \cdot g - \tau_{' num2str(app.v) ', ' num2str(d) '}$$'], 'Interpreter',"latex", 'FontSize', 20);
                xlabel(app.UIAxes, '$x$', 'Interpreter', 'latex', 'FontSize', 18);
                grid_bounds = [min(app.s)-2*app.T_elem max(app.s)+2*app.T_elem];
                app.xvals = linspace(grid_bounds(1), grid_bounds(2), 81);
                disp(size(app.xvals))
                app.mah_dist_x_s = pdist2(app.xvals'/app.T_elem, app.s_star);
                app.grid_size1 = length(app.xvals);
                app.prior_mean_vals = m0_t(app.xvals');
                app.true_vals = f(app.xvals');
                app.point_scat = scatter(app.UIAxes, app.s, repelem(0, length(app.f_interr)), 64, 'filled', 'MarkerFaceColor', 'k');
                hold(app.UIAxes, 'on')
                app.diff_surf = gobjects(1);
                
            elseif app.d == 2
                title(app.UIAxes, ['$$m^x_1 \cdot g - \tau_{' num2str(app.v) ', ' num2str(d) '}$$'], 'Interpreter',"latex", 'FontSize', 20);
                xlabel(app.UIAxes, '$x_1$', 'Interpreter', 'latex', 'FontSize', 18);
                ylabel(app.UIAxes, '$x_2$', 'Interpreter', 'latex', 'FontSize', 18);
                grid_bounds = [min(app.s(:,1))-2*app.T_elem max(app.s(:,1))+2*app.T_elem...
                    min(app.s(:,2))-2*app.T_elem max(app.s(:,2))+2*app.T_elem];
                grid_ratio = (grid_bounds(4) - grid_bounds(3))/...
                    (grid_bounds(2) - grid_bounds(1));
                [xgrid, ygrid] = meshgrid(linspace(grid_bounds(1), grid_bounds(2), 81),...
                    linspace(grid_bounds(3), grid_bounds(4), 81));
                [app.grid_size1, app.grid_size2] = size(xgrid);
                app.xvals = xgrid(:);
                app.yvals = ygrid(:);
                app.mah_dist_x_s = pdist2([app.xvals app.yvals]/app.T_elem, app.s_star);
                app.prior_mean_vals = reshape(m0_t([app.xvals app.yvals]), app.grid_size1, app.grid_size2);
                app.true_vals = reshape(f([app.xvals app.yvals]), app.grid_size1, app.grid_size2);
                app.point_scat = scatter3(app.UIAxes, app.s(:,1), app.s(:,2), repelem(0, length(app.f_interr)), 64, 'filled', 'MarkerFaceColor', 'k');
                hold(app.UIAxes, 'on')
                pbaspect(app.UIAxes, [1 grid_ratio geomean([grid_ratio 1/grid_ratio])])
                app.diff_surf = gobjects(1);
                
            else
                title(app.UIAxes, ['$m^x_1 \cdot g - \tau_{' num2str(app.v) ', ' num2str(app.d) '}$'], 'Interpreter',"latex", 'FontSize', 20);
                subtitle(app.UIAxes, '$\left(x_i = 0\ \mathrm{for}\  i > 2\right)$', 'Interpreter',"latex", 'FontSize', 16);
                xlabel(app.UIAxes, '$x_1$', 'Interpreter', 'latex', 'FontSize', 18);
                ylabel(app.UIAxes, '$x_2$', 'Interpreter', 'latex', 'FontSize', 18);
                grid_bounds = [min(app.s(:,1))-2*app.T_elem max(app.s(:,1))+2*app.T_elem...
                    min(app.s(:,2))-2*app.T_elem max(app.s(:,2))+2*app.T_elem];
                grid_ratio = (grid_bounds(4) - grid_bounds(3))/...
                    (grid_bounds(2) - grid_bounds(1));
                [xgrid, ygrid] = meshgrid(linspace(grid_bounds(1), grid_bounds(2), 81),...
                    linspace(grid_bounds(3), grid_bounds(4), 81));
                [app.grid_size1, app.grid_size2] = size(xgrid);
                app.xvals = xgrid(:);
                app.yvals = ygrid(:);
%                 app.mah_dist_x_s = pdist2([app.xvals app.yvals]/app.T_elem, app.s_star(:,1:2));
                app.mah_dist_x_s = pdist2([app.xvals app.yvals zeros(length(app.xvals), app.d - 2)]/app.T_elem, app.s_star);
%                 disp(max(abs(app.mah_dist_x_s - mah_dist_2)))
%                 disp(size(mah_dist_2))
%                 disp(size(app.mah_dist_x_s))
                app.prior_mean_vals = reshape(m0_t([app.xvals app.yvals repelem(0,length(app.xvals), app.d-2)]), app.grid_size1, app.grid_size2);
                app.true_vals = reshape(f([app.xvals app.yvals repelem(0, length(app.xvals), app.d-2)]), app.grid_size1, app.grid_size2);
                app.point_scat = scatter3(app.UIAxes, app.s(1:(2*l-1),1), app.s(1:(2*l-1),2), repelem(0, length(app.f_interr(1:(2*l-1)))), 64, 'filled', 'MarkerFaceColor', 'k');
                hold(app.UIAxes, 'on')
                pbaspect(app.UIAxes, [1 grid_ratio geomean([grid_ratio 1/grid_ratio])])
                app.diff_surf = gobjects(1);
            end
            
            title(app.lap_axes, 'Posterior of $F$, $\mathcal{N}\left(m_1, C_1\right)$', 'Interpreter', 'latex', 'FontSize', 20);
        end

        % Value changed function: alpha_slider, gamma_slider, 
        % lambda_slider
        function SliderValuesChanged(app, event)
            lambda = app.lambda_slider.Value;
            disp(['lambda: ' num2str(lambda)]);
            gamma = 2^app.gamma_slider.Value;
            disp(['gamma: ' num2str(gamma)]);
          
            % Initialize
            delete(app.diff_surf);
            hold(app.lap_axes, 'off')
            
            % Get necessary stuff to calculate posterior GP mean given
            % hyperparameters
            if app.d == 1
                interrs = exp(app.d*(log(gamma)+log(app.T_elem)))*...
                    (exp(app.f_interr -log(normpdf(app.s_star/gamma)))-...
                    (2*pi)^app.d*exp(app.logf_at_mode + log(normpdf(sqrt(gamma^2-1)*app.s_star/gamma))));
            else
                interrs = exp(app.d*(log(gamma)+log(app.T_elem)))*...
                    (exp(app.f_interr -log(mvnpdf(app.s_star/gamma)))-...
                    (2*pi)^app.d*exp(app.logf_at_mode + log(mvnpdf(sqrt(gamma^2-1)*app.s_star/gamma))));
            end
            
            gram_mat = Cx_0(app, app.mah_dist_s, lambda);
            
%             C0_t = se_decay_gram(app.s, lambda, gamma);
            pointz = gram_mat\interrs;
            
            GP_weightz = Cx_0(app, app.mah_dist_x_s, lambda);

            if app.d == 1
                delete(app.diff_surf);

%                 c_t_cells = arrayfun(@(i) gp_weights(app, app.xvals(i)./app.rot_scale_mat(1,1), lambda, gamma, app.d),...
%                     1:(app.grid_size1), 'UniformOutput', false);
%                 c_t_vals = cat(1, c_t_cells{:});

                warning('off');
                app.diff_surf = plot(app.UIAxes, app.xvals, app.prior_mean_vals + app.g(app.xvals', gamma).*GP_weightz*pointz - app.true_vals, ...
                    'LineWidth', 1.25, 'Color', 'k');
                
                
            else
                delete(app.diff_surf);
                
%                 c_t_cells = arrayfun(@(i) gp_weights(app, [app.xvals(i)./app.rot_scale_mat(1,1) app.yvals(i)./app.rot_scale_mat(2,2)], lambda, gamma, app.d),...
%                     1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);
%                 c_t_vals = cat(1, c_t_cells{:});

                warning('off');
                %disp(size(app.g([app.xvals app.yvals], gamma)))
                app.diff_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
                    app.prior_mean_vals + reshape(app.g([app.xvals app.yvals zeros(length(app.xvals), app.d-2)], gamma).*GP_weightz*pointz, app.grid_size1, app.grid_size2) - app.true_vals,...
                    'FaceAlpha', 0.6, 'EdgeColor', [0.5 0.5 0.5]);
                
%             else
%                 delete(app.diff_surf);
% 
% %                 c_t_cells = arrayfun(@(i) gp_weights(app, [app.xvals(i)./app.rot_scale_mat(1,1) app.yvals(i)./app.rot_scale_mat(2,2) repelem(0, 1, app.d-2)], lambda, gamma, app.d),...
% %                     1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);
% %                 c_t_vals = cat(1, c_t_cells{:});
%                 
%                 gauss_weights = arrayfun(@(i) exp(-((app.xvals(i)./app.rot_scale_mat(2,2)).^2 + (app.yvals(i)./app.rot_scale_mat(2,2)).^2)/2),...
%                     1:(app.grid_size1*app.grid_size2), 'UniformOutput', false);
%                 gauss_weights = reshape(cat(1, gauss_weights{:}), app.grid_size1, app.grid_size2);
%                 
%                 % Used this bit for sanity checks to see how different
%                 % shapes affected the behaviuor
% %                 disp('normalized interrogation differences:');
% %                 disp((app.f_interr - app.gauss_interr)/app.f_at_mode);
% %                 disp('weights:');
% %                 disp(c_t_vals(6865,:)/C0_t);
% %                 disp('Normalized correction:');
% %                 disp(c_t_vals(6865,:)*pointz/app.f_at_mode);
% %                 disp('Normalized prior mean value:');
% %                 disp(app.prior_mean_vals(40,66)/app.f_at_mode);
% %                 disp('Normalized true value:');
% %                 disp(app.true_vals(40,66)/app.f_at_mode);
% %                 disp('Normalized prior difference:');
% %                 disp((app.true_vals(40,66) - app.prior_mean_vals(40,66))/app.f_at_mode);
% %                 disp('Normalized posterior difference:');
% %                 disp((app.true_vals(40,66) - app.prior_mean_vals(40,66) - c_t_vals(6865,:)*pointz)/app.f_at_mode);
% 
%                 warning('off');
%                 app.diff_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
%                     app.prior_mean_vals + gauss_weights.*reshape(app.g(app.xvals, gamma).*GP_weightz*pointz, app.grid_size1, app.grid_size2) -...
%                     app.true_vals,...
%                     'FaceAlpha', 0.65);
% %                 app.diff_surf = surf(app.UIAxes, unique(app.xvals(:)), unique(app.yvals(:)),...
% %                     app.prior_mean_vals - app.true_vals,...
% %                     'FaceAlpha', 0.65);
%                 legend(app.UIAxes, [app.point_scat], {'Interrogation points'},...
%                     'Location', 'northoutside');
            end
            legend(app.UIAxes, [app.point_scat], {'Interrogation points'},...
                    'Location', 'southoutside', 'interpreter', 'latex', 'FontSize', 14);
            % Get posterior mean and variance for integral diagnostic
            int_weightz = (lambda^2/(gamma^2+lambda^2))^(app.d/2)*exp(-sum(app.s_star.^2, 2)/(2*(gamma^2+lambda^2)));    

            C0 = (lambda^2/(2*gamma^2 + lambda^2))^(app.d/2);
            post_var_unscaled = C0 - (int_weightz'/gram_mat)*int_weightz;
            
            
            post_mean = app.lap_approx + int_weightz'*pointz;
            %disp(exp(app.d*log(app.T_elem)))

            app.alpha = abs(1.96*sqrt(post_var_unscaled)*exp(app.logf_at_mode+app.d*log(app.T_elem))/(post_mean - app.lap_approx))^(2/app.d);
            disp(['alpha: ' num2str(app.alpha)]);
%             disp(exp(app.d*(2*log(app.T_elem))))
            post_var = post_var_unscaled*exp(app.d*(2*log(app.T_elem) - log(app.alpha)) + 2*app.logf_at_mode);
            %app.sqrt_det_inv_hess^2*app.tau_at_mode^2/app.alpha^2;
            
            disp(['Posterior integral mean: ' num2str(post_mean)]);
            disp(['Posterior integral variance: ' num2str(post_var)]);
            
            % Plot integral diagnostic
            lower_bound = norminv(0.025, post_mean, sqrt(post_var));
            upper_bound = norminv(0.975, post_mean, sqrt(post_var));
            lap_dist = fplot(app.lap_axes, @(x) normpdf(x, post_mean, sqrt(post_var)),...
                [lower_bound - 0.35*(upper_bound - lower_bound) upper_bound + 0.35*(upper_bound - lower_bound)], '-k','LineWidth', 1.5);
            hold(app.lap_axes, 'on')
            lap_line = xline(app.lap_axes,app.lap_approx, '--b', 'LineWidth', 1.25);
            true_line = xline(app.lap_axes,1, '--r','LineWidth', 1.25);
            quant_line = xline(app.lap_axes, lower_bound, ':k',...
                'LineWidth', 1.35);
            quant_line = xline(app.lap_axes, upper_bound, ':k',...
                'LineWidth', 1.35);
            legend(app.lap_axes,[lap_dist, quant_line, lap_line, true_line],...
                {'Integral posterior', '95\% interval',...
                'Laplace approximation', 'True integral value'}, 'Location', 'southoutside', 'interpreter', 'latex', 'FontSize', 14);
        end

        % Button pushed function: GeneratefiguretosaveButton
        function GeneratefiguretosaveButtonPushed(app, event)
            
            fig = tiledlayout(1,3, 'TileSpacing', 'Compact', 'Padding', 'Compact');
            hSub1 = nexttile([1 2]);
            set(gca, 'FontSize', 16);
            
            %disp(app.UIAxes.Children);
            copyobj(app.UIAxes.Children, hSub1);
            set(gca,'TickLabelInterpreter','latex'); 
 
            title(['$$m^x_1 \cdot g - \tau_{' num2str(app.v) ', ' num2str(app.d) '}$$'], 'Interpreter',"latex", 'FontSize', 20);
            if app.d > 2
                subtitle('$\left(x_i = 0\ \mathrm{for}\  i > 2\right)$', 'Interpreter',"latex", 'FontSize', 16);
            end
            if app.d == 1
                xlabel('$x$', 'Interpreter', 'latex', 'FontSize', 18);
            else
                xlabel('$x_1$', 'Interpreter', 'latex', 'FontSize', 18);
                ylabel('$x_2$', 'Interpreter', 'latex', 'FontSize', 18);
            end
            legend({'Interrogation points'},...
                'Location', 'southoutside', 'interpreter', 'latex', 'FontSize', 14);
            pbaspect(hSub1, app.UIAxes.PlotBoxAspectRatio)

            
           hSub2 = nexttile;
            set(gca, 'FontSize', 16)
            copyobj(app.lap_axes.Children, hSub2);
            set(gca,'TickLabelInterpreter','latex'); 
            legend({'Integral posterior', '95\% interval',...
                'Laplace approximation', 'True integral value'}, 'Location', 'southoutside', 'interpreter', 'latex', 'FontSize', 14);
            title(gca, 'Posterior of $F$, $\mathcal{N}\left(m_1, C_1\right)$', 'Interpreter', 'latex', 'FontSize', 20);
            
            %copyobj(app.UIAxes.Legend, hSub1);
            %sub2 = copyobj(app.lap_axes.Parent, fig);
            %saveas(fig, 'fig.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.lap_axes = uiaxes(app.UIFigure);
            title(app.lap_axes, 'Laplace Approximation Diagnostic')
            app.LengthscaleLabel = uilabel(app.UIFigure);
            app.LengthscaleLabel.Text = 'Length-scale: λ';
            app.lambda_slider = uislider(app.UIFigure);
            app.lambda_slider.Limits = [1e-10 5];
            app.lambda_slider.MajorTicks = [0.01 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5];
            app.lambda_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.lambda_slider.MinorTicks = [];
            app.lambda_slider.Value = 4.224;
            app.PriorvariancedecayLabel = uilabel(app.UIFigure);
            app.PriorvariancedecayLabel.Text = 'Prior variance decay: γ';
            app.gamma_slider = uislider(app.UIFigure);
            app.gamma_slider.Limits = [-1 6];
            app.gamma_slider.MajorTicks = [-1 0 1 2.32192809488736 3.32192809488736 4.64385618977472 5.64385618977472 6.64385618977472 7.64385618977472];
            app.gamma_slider.MajorTickLabels = {'0.5', '1', '2', '5', '10', '25', '50', '100', '200'};
            app.gamma_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.gamma_slider.MinorTicks = [];
            app.gamma_slider.Value = 0.348718614989784;
            app.PriorprecisionLabel = uilabel(app.UIFigure);
            app.PriorprecisionLabel.Text = 'Prior precision: α';
            app.alpha_slider = uislider(app.UIFigure);
            app.alpha_slider.Limits = [0 0.602059991327962];
            app.alpha_slider.MajorTicks = [0 0.698970004336019 1 1.69897000433602 2 3 4];
            app.alpha_slider.MajorTickLabels = {'1', '5', '10', '50', '100', '1000', '10000'};
            app.alpha_slider.ValueChangedFcn = createCallbackFcn(app, @SliderValuesChanged, true);
            app.alpha_slider.MinorTicks = [];
            app.alpha_slider.Value = 0.493290714306025;
            app.GeneratefiguretosaveButton = uibutton(app.UIFigure, 'push');
            app.GeneratefiguretosaveButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefiguretosaveButtonPushed, true);
            app.GeneratefiguretosaveButton.Text = 'Generate figure to save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T_nD_diff_laplace_diagnostic_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end